% description: Copyright 2018 Luca Della Santina
classdef Clarinet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClarinetUIFigure           matlab.ui.Figure
        Lamp                       matlab.ui.control.Lamp
        Log                        matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
        pnlToolbar                 matlab.ui.container.Panel
        btnFind                    matlab.ui.control.Button
        btnOpen                    matlab.ui.control.Button
        btnAbout                   matlab.ui.control.Button
        btnExport                  matlab.ui.control.Button
        btnSave                    matlab.ui.control.Button
        btnProcess                 matlab.ui.control.Button
        btnSettings                matlab.ui.control.Button
        cmbProcessors              matlab.ui.control.DropDown
        btnBatchAdd                matlab.ui.control.Button
        btnBatchClear              matlab.ui.control.Button
        lblProcessors              matlab.ui.control.Label
        btnBatchOpen               matlab.ui.control.Button
        btnBatchSave               matlab.ui.control.Button
        btnBatchApply              matlab.ui.control.Button
        btnTreeRemove              matlab.ui.control.Button
        btnTreeClear               matlab.ui.control.Button
        XScaleType                 matlab.ui.control.Switch
        btnGroupVisualizationType  matlab.ui.container.ButtonGroup
        btnPlot2D                  matlab.ui.control.ToggleButton
        btnPlot3D                  matlab.ui.control.ToggleButton
        UITree                     matlab.ui.container.Tree
        pnlEpochTools              matlab.ui.container.ButtonGroup
        btnTagEmpty                matlab.ui.control.ToggleButton
        btnTagYellow               matlab.ui.control.ToggleButton
        btnTagGreen                matlab.ui.control.ToggleButton
        btnTagOrange               matlab.ui.control.ToggleButton
        btnTagBlue                 matlab.ui.control.ToggleButton
        btnTagRed                  matlab.ui.control.ToggleButton
        btnTagBlack                matlab.ui.control.ToggleButton
        btnEpochTypes              matlab.ui.control.Switch
        btnTagRemove               matlab.ui.control.Button
        btnTagAdd                  matlab.ui.control.Button
        btnResponseType            matlab.ui.control.Switch
        tblMeta                    matlab.ui.control.Table
        Mode                       matlab.ui.control.Switch
        pnlFeatureTools            matlab.ui.container.Panel
        cmbFeatures                matlab.ui.control.DropDown
        FeatureLabel               matlab.ui.control.Label
        cmbSplitParam              matlab.ui.control.DropDown
        cmbDevices                 matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Cells           = [] % List of cells to extract features from
        Tree            = [] % Analysis tree
        Conditions      = [] % Search conditions table
        SelectedEpochs  = [] % List of Epoch indexes seected using findEpochs
        dlgFind         = [] % Dialog window used for predicate search
        dlgAbout        = [] % Dialog window showing app info
        dlgGroupBy      = [] % Dialog window to group epochs by tag values
        pipeline        = [] % Processing Pipeline list
        rootNodeCells   = [] % Root node handle of the UITree component in Epochs mode
        rootNodeAnalysis= [] % Root node handle of the UITree component in Features mode
        LocalFolder     = '' % Local folder in which settings and custom processors are stored
        selectedTagRows = [] % Row indexes the user selected in the Tags table
        ICON_GROUP      = 'group.png'
        ICON_TAG        = 'tag.png'
        ICON_EXTRACTOR  = 'extractor.png'
    end
    
    methods (Access = private)
        
        function refreshUITree(app)
            % Refreshes the UITree component from current app.CellData or app.Tree
            app.UITree.clo; % Clear previous UI tree
            app.tblMeta.Data = [];
            switch app.Mode.Value
                case 'Features'
                    dt = app.Tree.depthtree;
                    depth = 1;
                    parentNodes = [];
                    newParentNodes = [];
                    for nodeIdx = app.Tree.breadthfirstiterator
                        data = app.Tree.Node(nodeIdx);
                        data = data{1};
                        newDepth = dt.Node(nodeIdx);
                        newDepth = newDepth{1};
                        switch class(data)  
                            case 'Group'
                                % This is the root node
                                app.rootNodeAnalysis = uitreenode(app.UITree,'Text', 'Features', 'NodeData',1);                                
                            case 'CellData'
                                FileName = data.get('h5File');
                                CellName = data.get('label');
                                if iscell(CellName)
                                    CellName = [CellName{:}];
                                end
                                
                                node = uitreenode(app.rootNodeAnalysis,'Text',[FileName '::' CellName],'NodeData', nodeIdx, 'Icon', app.ICON_GROUP);
                                if isempty(newParentNodes)
                                    newParentNodes = node;
                                else
                                    newParentNodes(end+1) = node; %#ok
                                end
                            case 'EpochGroup'
                                if newDepth == depth
                                    % Adding a new node within same depth order
                                    parentIdx = app.Tree.getparent(nodeIdx);
                                    parentNode = parentNodes([parentNodes.NodeData]== parentIdx);
                                    node = uitreenode(parentNode,'Text',[data.splitParameter ' = ' data.splitValue],'NodeData', nodeIdx, 'Icon', app.ICON_TAG);
                                    newParentNodes(end+1) = node;
                                else
                                    % Adding a new node in the next depth order
                                    parentNodes = newParentNodes;
                                    parentIdx = app.Tree.getparent(nodeIdx);
                                    parentNode = parentNodes([parentNodes.NodeData]== parentIdx);
                                    node = uitreenode(parentNode, 'Text',[data.splitParameter ' = ' data.splitValue],'NodeData', nodeIdx, 'Icon', app.ICON_TAG);
                                    newParentNodes = node;
                                end
                        end
                        depth = newDepth;                        
                    end
                    
                    app.btnTreeRemove.Enable = 'off';
                    app.btnTreeClear.Enable  = 'on';
            end                    
            app.UITree.expand('all');
        end
        function populateUITree(app)
            % Populate the UITree component
            % When in Epochs mode, use data from available CellData objects in app.Cells
            % When in Features mode, uses data from CellData to create the tree app.Tree
            
            app.UITree.clo; % Clear previous UI tree
            app.tblMeta.Data = [];
            
            switch app.Mode.Value
                case 'Epochs'
                    if isempty(app.Cells)
                        return
                    end
                    
                    app.rootNodeCells = uitreenode(app.UITree,'Text','Epochs','NodeData',[]);
                    for j = 1:numel(app.Cells)
                        FileName = app.Cells(j).get('h5File');
                        CellName = app.Cells(j).get('label');
                        if iscell(CellName)
                            CellName = [CellName{:}];
                        end
                        
                        exp = uitreenode(app.rootNodeCells,'Text',[FileName '::' CellName ' (' app.Cells(j).experimentDate ')'],'NodeData',[]);
                        for i = 1:numel(app.Cells(j).epochs)
                            epoch = app.Cells(j).epochs(i);
                            if strcmp(app.btnEpochTypes.Value, 'Searched') && (isempty(epoch.filtered) || ~epoch.filtered)
                                continue
                            end
                            
                            if epoch.hasAttribute('tagColor') && ~strcmp(epoch.attributes('tagColor'),'')
                                tagColor = [epoch.attributes('tagColor') '.png'];
                            else
                                tagColor = '';
                                epoch.attributes('tagColor') = tagColor;
                            end
                            uitreenode(exp,'Text',['Epoch ' num2str(epoch.get('epochNum')) datestr(epoch.get('epochTime'), ' (mmm dd, HH:MM:SS)')],...
                                'NodeData',epoch,'Icon', tagColor);
                        end
                    end
                case 'Features'                    
                    app.Tree = tree(Group('Features'));
                    app.rootNodeAnalysis = uitreenode(app.UITree,'Text', 'Features', 'NodeData',1);
                    for i = 1:numel(app.Cells)
                        FileName = app.Cells(i).get('h5File');
                        CellName = app.Cells(i).get('label');
                        if iscell(CellName)
                            CellName = [CellName{:}];
                        end
                        
                        [app.Tree, nodeIdx] = app.Tree.addnode(1, app.Cells(i));
                        uitreenode(app.rootNodeAnalysis,'Text',[FileName '::' CellName ' (' app.Cells(i).experimentDate ')'],'NodeData', nodeIdx, 'Icon', app.ICON_GROUP);
                    end
                    
                    app.btnFind.Enable       = 'off';
                    app.btnTreeRemove.Enable = 'off';
                    app.btnTreeClear.Enable  = 'on';
            end                    
            app.UITree.expand('all');
        end
    
        function populateFilterList(app)
            % Populates the list of available filters
            % When in Epochs mode, uses Epoch processors (bultin + custom)
            % When in Features mode, uses Feature extractors (builtin) TODO custom extractors
            switch app.Mode.Value
                case 'Epochs'            
                    import processors.*
                    filters = meta.package.fromName('processors').FunctionList;
                    filterNames = {filters.Name};
                    filterNames = sort(filterNames);
                    
                    import customProcessors.*
                    if ~isempty(meta.package.fromName('customProcessors'))
                        customFilters = meta.package.fromName('customProcessors').FunctionList;
                        customFilterNames = {customFilters.Name};
                        customFilterNames = sort(customFilterNames);
                        app.cmbProcessors.Items = [customFilterNames, filterNames];
                    else
                        app.cmbProcessors.Items = filterNames;
                    end
                    app.cmbProcessorsValueChanged;
                case 'Features'
                    import extractors.*
                    filters = meta.package.fromName('extractors').FunctionList;
                    filterNames = {filters.Name};
                    filterNames = sort(filterNames); 
                    
                    import customExtractors.*
                    if ~isempty(meta.package.fromName('customExtractors'))
                        customFilters = meta.package.fromName('customExtractors').FunctionList;
                        customFilterNames = {customFilters.Name};
                        customFilterNames = sort(customFilterNames);
                        app.cmbProcessors.Items = [customFilterNames, filterNames];
                    else
                        app.cmbProcessors.Items = filterNames;
                    end                    
                    app.cmbProcessorsValueChanged;                  
            end            
            
            % Reset the pipeline because processors/extractors in there might not be available anymore
            app.pipeline = [];
        end
                                        
        function plotEpoch(app, epochs)
            % Plots the data of passed epochs in the app.UIaxes component
            cla(app.UIAxes,'reset');
            app.UIAxes.XGrid = true;
            app.UIAxes.YGrid = true;
            app.UIAxes.ZGrid = true;
            app.UIAxes.Color = [0.94,0.94,0.94];
            
            epochNames = repmat({''}, numel(epochs),1);
            for i = 1:numel(epochs)
                epochNames{i} = ['Epoch ' num2str(epochs(i).get('epochNum'))]; %Remember epoch names for legend
                switch app.btnResponseType.Value
                    case 'Raw'
                        response = epochs(i).getResponse(app.cmbDevices.Value);
                    case 'Processed'
                        if epochs(i).hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                            response = epochs(i).getDerivedResponse('filteredResponse', app.cmbDevices.Value);
                        else
                            disp('No filtered Response available, using raw');
                            response = epochs(i).getResponse(app.cmbDevices.Value);
                        end
                end
                
                % Plot epoch in a 3D space if button is pressed, otherwise use 2D cartesian plane
                if app.btnPlot3D.Value
                    plot3(app.UIAxes, 1:numel(response.quantity), repmat(i, 1, numel(response.quantity)), response.quantity');
                else
                    plot(app.UIAxes, 1:numel(response.quantity), response.quantity');
                end
                
                % Define the range to display in the X and Y axis
                if i == 1
                    Xmax = numel(response.quantity);
                    Ymax = max(response.quantity);
                    Ymin = min(response.quantity);
                else
                    Xmax = max([Xmax, numel(response.quantity)]);
                    Ymax = max([Ymax, max(response.quantity)]);
                    Ymin = min([Ymin, min(response.quantity)]);
                end
                
                if numel(epochs)>1
                    hold(app.UIAxes, 'on');
                end
            end
            
            % Handle special cases before setting the Y axis
            if Ymin == Ymax % no signal or signal has a constant value
                Ymin = Ymin -1;
                Ymax = Ymax +1;
            elseif Ymin==0 && Ymax==1 % binary value (0-1)
                Ymin = -0.3;
                Ymax = 1.3;
            end
            
            if app.btnPlot3D.Value
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [0 numel(epochs)+1]);
                zlim(app.UIAxes, [Ymin Ymax]);
            else
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [Ymin Ymax]);
            end
            if isfield(response, 'units')
                units = response.('units');
                units = deblank(units(:,1)');
                ylabel(app.UIAxes, units);
            else
                ylabel(app.UIAxes, '');
            end
            title(app.UIAxes, epochs(1).get('displayName'));

            
            switch app.XScaleType.Value
                case 'Time (s)'     % Relabel 'XTick' With 'XTickLabel' Values scaled by sampleRate
                    meta = epochs(1).toStructure;
                    xt = get(app.UIAxes, 'XTick');
                    set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt/meta.sampleRate);
                case 'Samples'      % Use original XTickLabels
                    xt = get(app.UIAxes, 'XTick');
                    set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt);
            end
            legend(app.UIAxes, epochNames, 'Location','northeast');
        end
        
        function plotFeature(app, group, featureName)
            % Plots the data of featureName available in passed EpochGroup group 
            cla(app.UIAxes,'reset');
            app.UIAxes.XGrid = true;
            app.UIAxes.YGrid = true;
            app.UIAxes.ZGrid = true;
            app.UIAxes.Color = [0.94,0.94,0.94];

            dataGroup = group.getFeatureData(featureName); % Can either be a cell array or an array of struct 
            epochNames = repmat({''}, numel(dataGroup),1);            
            for i=1:numel(dataGroup)
                epochNames{i} = ['Epoch ' num2str(group.epochs(i).get('epochNum'))]; %Remember epoch names for legend                                        
                switch class(dataGroup)
                    case 'cell'
                        data = dataGroup{i};
                    case 'struct'
                        data = dataGroup(i).quantity;
                end
                
                % Plot epoch in a 3D space if button is pressed, otherwise use 2D cartesian plane
                if i>1
                    hold(app.UIAxes, 'on');
                end
                if app.btnPlot3D.Value
                    plot3(app.UIAxes, 1:numel(data), repmat(i, 1, numel(data)), data);
                else
                    plot(app.UIAxes, 1:numel(data), data);
                end
                
                % Define the range to display in the X and Y axis
                if i == 1
                    Xmax = numel(data);
                    Ymax = max(data);
                    Ymin = min(data);
                else
                    Xmax = max([Xmax, numel(data)]);
                    Ymax = max([Ymax, max(data)]);
                    Ymin = min([Ymin, min(data)]);
                end                
            end
            
            % Handle special cases before setting the Y axis
            if Ymin == Ymax % no signal or signal has a constant value
                Ymin = Ymin -1;
                Ymax = Ymax +1;
            elseif Ymin==0 && Ymax==1 % binary value (0-1)
                Ymin = -0.3;
                Ymax = 1.3;
            end
            
            if app.btnPlot3D.Value
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [0 numel(dataGroup)+1]);
                zlim(app.UIAxes, [Ymin Ymax]);
            else
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [Ymin Ymax]);
            end
            
            feat = group.getFeatures(featureName);
            %feat(1).description
            if isfield(feat(1).description, 'units')
                ylabel(app.UIAxes, feat(1).description.units);
            else
                ylabel(app.UIAxes, '');
            end
            title(app.UIAxes, strrep(featureName, '_', ' '));            
            legend(app.UIAxes, epochNames, 'Location', 'northeast');
        end
        
        function plotAttribute(app, cellData, attrName)
            % Plots attrName values for each epoch in CellData (metadata of each epoch)
            cla(app.UIAxes,'reset');
            app.UIAxes.XGrid = true;
            app.UIAxes.YGrid = true;
            app.UIAxes.ZGrid = true;
            app.UIAxes.Color = [0.94,0.94,0.94];

            [vals, description] = cellData.getEpochValues(attrName);
            if ~isnumeric(vals)
                return
            end
            data = vals;
            
            if app.btnPlot3D.Value
                scatter3(app.UIAxes, 1:numel(data), repmat(1, 1, numel(data)), data);
            else
                scatter(app.UIAxes, 1:numel(data), data);
                for i=1:numel(data)
                    if data(i)>0
                        line(app.UIAxes, [i i], [0 data(i)]);
                    else
                        line(app.UIAxes, [i i], [data(i) 0]);
                    end
                end
            end
            
            Xmax = numel(data);
            Ymax = max(data);
            Ymin = min(data);

            % Handle special cases before setting the Y axis
            if Ymin == Ymax % no signal or signal has a constant value
                Ymin = Ymin -1;
                Ymax = Ymax +1;
            elseif Ymin==0 && Ymax==1 % binary value (0-1)
                Ymin = -0.3;
                Ymax = 1.3;
            end
            
            if app.btnPlot3D.Value
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [0 2]);
                zlim(app.UIAxes, [Ymin Ymax]);
            else
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [Ymin Ymax]);
            end

            title(app.UIAxes, description);            
        end

        function plotAverage(app, device, feature, splitParam)
            if any([isempty(device), isempty(feature), isempty(splitParam)])
                return
            end
            cla(app.UIAxes,'reset');
            app.UIAxes.XGrid = true;
            app.UIAxes.YGrid = true;
            app.UIAxes.ZGrid = true;
            app.UIAxes.Color = [0.94,0.94,0.94];
            
            % Data will be store in a map with keys being the possible levels of the splitParamer and values 
            % being the feature data averaged per cell
            valsMap = containers.Map;
            
            % Collect data from all the experiments inside valsMap, for any given level of the splitParam
            % Each experiment should have only one corresponding EpochGroup node with that feature
            for idx = app.Tree.breadthfirstiterator
                data = app.Tree.Node(idx);
                data = data{1};
                if ~isa(data, 'EpochGroup') 
                    continue
                elseif ~strcmp(data.splitParameter, splitParam)
                    continue
                end
                
                featData = data.getFeatureData(feature);
                if ~valsMap.isKey(data.splitValue)
                    valsMap(data.splitValue) = {featData};
                else
                    vals = valsMap(data.splitValue);
                    vals{end+1} = featData;
                    valsMap(data.splitValue) = vals;
                end
            end            
            %assignin('base','valsMap', valsMap); %enable for debugging
            
            %average data in valsMap
            keys = valsMap.keys;
            for i = 1:numel(keys)
                key = keys{i};
                vals = valsMap(key); % Get the values
                vals = [vals{:}]; % Make them into a linear cell array
                valsMatrix = zeros(numel(vals{1}),numel(vals)); % Convert them into a numeric array
                for j=1:numel(vals)
                    valsMatrix(:,j) = vals{j};
                end
                fmean = mean(valsMatrix, 2);
                fvarSD  = std(valsMatrix,[],2);
                fvarSEM = std(valsMatrix,[],2)/sqrt(size(data,2));

                % Add results as feture of the root node
                %group = app.Tree.Node(1);
                %group = group{1};
                %group.createFeature([splitParam '_' key '_MEAN'], fmean,   'append', true);
                %group.createFeature([splitParam '_' key '_SD'],   fvarSD,  'append', true);
                %group.createFeature([splitParam '_' key '_SEM'],  fvarSEM, 'append', true);
                %app.Tree.set(1, group);
                if i==1
                    hold(app.UIAxes, 'off');
                else
                    hold(app.UIAxes, 'on');
                end

                if app.btnPlot3D.Value
                    plot3(app.UIAxes, 1:numel(fmean), repmat(i, 1, numel(fmean)), fmean);
                else
                    plot(app.UIAxes, 1:numel(fmean), fmean);
                end
                

                % Define the range to display in the X and Y axis
                if i == 1
                    Xmax = numel(fmean);
                    Ymax = max(fmean);
                    Ymin = min(fmean);
                else
                    Xmax = max([Xmax, numel(fmean)]);
                    Ymax = max([Ymax, max(fmean)]);
                    Ymin = min([Ymin, min(fmean)]);
                end                        
            end
            
            
            % Handle special cases before setting the Y axis
            if Ymin == Ymax % no signal or signal has a constant value
                Ymin = Ymin -1;
                Ymax = Ymax +1;
            elseif Ymin==0 && Ymax==1 % binary value (0-1)
                Ymin = -0.3;
                Ymax = 1.3;
            end
            
            if app.btnPlot3D.Value
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [0 numel(keys)+1]);
                zlim(app.UIAxes, [Ymin Ymax]);
            else
                xlim(app.UIAxes, [1 Xmax]);
                ylim(app.UIAxes, [Ymin Ymax]);
            end
            
            title(app.UIAxes, ['Average ' strrep(app.cmbFeatures.Value, '_', ' ') ' by ' app.cmbSplitParam.Value]);            
            legend(app.UIAxes, keys, 'Location','northeast');
            
        end    

        function cellData = findParentCellDataUITree(app, node)
            % Search upstream of selected UInode in app.Tree for the node holding CellData
            cellData = [];
            currentNode = node;
            while ~isempty(currentNode.Parent)
                nodeIdx = currentNode.NodeData;
                data = app.Tree.Node(nodeIdx);
                data = data{1};
                
                if isa(data, 'CellData')
                    cellData = data;
                    return
                end               
                currentNode = currentNode.Parent;
            end
        end

        function cellData = findParentCellDataTree(app, nodeIdx)
            % Search upstream of nodeIdx in app.Tree for the node holding CellData
            cellData = [];
            while ~isempty(nodeIdx)
                data = app.Tree.Node(nodeIdx);
                data = data{1};
                
                if isa(data, 'CellData')
                    cellData = data;
                    return
                end               
                nodeIdx = app.Tree.getparent(nodeIdx);
            end
        end

        function sharedKeys = getFeatureKeysAcrossCells(app)
            % Trasverse the tree to find Feature keys shared across experiments
            sharedKeys = {};
            CellDataNodes = app.rootNodeAnalysis.Children;
            for i=1:numel(CellDataNodes)
                node = CellDataNodes(i);
                for j = 1: numel(node.Children)
                    child = node.Children(j);
                    nodeIdx = child.NodeData;
                    data = app.Tree.Node(nodeIdx);
                    data = data{1};
                    keys = data.getFeatureKey;
                    
                    % Accumulate feature only if shared across cells examined so far
                    if isempty(sharedKeys)
                        sharedKeys = keys;
                    else
                        sharedKeys = intersect(sharedKeys, keys);
                    end
                end
            end
        end
        
        function sharedKeys = getFeatureKeysAcrossEpochGroups(app, selectedNodes)
            % Trasverse the tree to find Feature keys shared across experiments
            sharedKeys = {};
            for i=1:numel(selectedNodes)
                nodeIdx = selectedNodes(i).NodeData;
                data = app.Tree.Node(nodeIdx);
                data = data{1};
                keys = data.getFeatureKey;
                
                % Accumulate feature only if shared across cells examined so far
                if isempty(sharedKeys)
                    sharedKeys = keys;
                else
                    sharedKeys = intersect(sharedKeys, keys);
                end
            end
        end
    
        function devices = getDevicespAcrossCells(app)
            % Trasverse the tree to find which devices are shared across experiments
            devices = []; 
            CellDataNodes = app.rootNodeAnalysis.Children;
            if isempty(CellDataNodes)
                return
            end
            
            for i=1:numel(CellDataNodes)
                nodeIdx = CellDataNodes(i).NodeData;
                data = app.Tree.Node(nodeIdx);
                data = data{1};
                devs =  data.epochs(1).get('devices');
                if isempty(devices)
                    devices = devs;
                else
                    devices = intersect(devices, devs);
                end
            end
        end
    
        function splitMap = getSplitStrategyMap(app)
            % Trasverses by breadth the tree "T" detecting split events.
            % Returns a map, keys = name of splitParameter, value = used splitValues
            splitMap = containers.Map; 

            for i = 1:numel(app.UITree.SelectedNodes)
                % Split every node that was created in the previous step
                for n = app.Tree.breadthfirstiterator
                    data = app.Tree.Node(n);
                    data = data{1};
                    
                    if isa(data, 'EpochGroup')
                        key = data.splitParameter;
                        if ~splitMap.isKey(key)
                            splitMap(key) = {data.splitValue};
                        else
                            % Add the new value if not already present for this key
                            splitMap(key) = union(splitMap(key), {data.splitValue});
                        end
                    end
                end
            end
        end    
    end
    
    methods (Access = public)
        
        function result = findEpochs(app, conditions, criteria)
            % Search for epoch matching the stated conditions according to a specific criteria
            % conditions = table of conditions, each row contains: [ propery | operator | value ]
            % criteria = [ Any | All ] (e.g. "Any" means query returns epochs which meet any of the conditions)
            
            CONDITIONS_MAP = containers.Map({'numeric', 'string'},...
                {{'==', '>', '<', '>=', '<=', '~='}, {'strcmpi', 'strfind', 'strcmp', 'regexp'}});
            epochs = app.Cells.epochs; % TODO fix search across cells
            
            arrayfun(@(e) setFiltered(e, false), epochs)
            query = linq(1 : numel(epochs));
            
            rows = size(conditions, 1);
            for row = 1 : rows
                property    = conditions{row, 1}; % returns a cell with the property
                property    = property{:};
                operator    = conditions{row, 2};
                operator    = string(operator);
                value       = conditions{row, 3}; % returns a cell with the value
                value       = value{:}; % needed to retrieve the actual values from a cell
                
                % find out whether current operator is numeric or string
                operatorIdx = cellfun(@(keySet) ismember(operator, keySet), CONDITIONS_MAP.values);
                keys        = CONDITIONS_MAP.keys;
                operatorType= keys{operatorIdx};
                
                if strcmp(operatorType, 'numeric')
                    predicateCondition = str2func(strcat('@(data, values) any(data.get(''', property, ''')', operator, 'values)'));
                    value = str2double(value);
                else
                    predicateCondition = str2func(strcat('@(data, values) any(', operator, '(data.get(''', property, '''), values))'));
                end
                
                predicate = @(data) predicateCondition(data, value);
                switch criteria
                    case 'All'
                        query = query.where(@(index) predicate(epochs(index)));
                    case 'Any'
                        if row == 1
                            query = query.where(@(index) predicate(epochs(index)));
                        else
                            newquery = linq(1 : numel(epochs));
                            newquery = newquery.where(@(index) predicate(epochs(index)));
                            query = query.concat(newquery);
                        end
                end
            end
            filteredIndices = query.toArray();
            
            % Update the 'filtered' property of each epoch according to result
            arrayfun(@(e) setFiltered(e, true), epochs(filteredIndices));
            arrayfun(@(e) setFiltered(e, false), epochs(setdiff(1:numel(epochs), filteredIndices)));
            
            result                  = filteredIndices;
            app.SelectedEpochs      = filteredIndices;  % Store list of selected epochs indexes
            app.Conditions          = conditions;       % Store conditions table for later
            app.btnEpochTypes.Value = 'Searched';
            app.btnEpochTypesValueChanged;
            
            %if numel(filteredIndices) > 0
            %    [p, v] = app.Cell.getUniqueParamValues(filteredIndices);
            %    result = KeyValueEntity(containers.Map(p, v));
            %else
            %    result = 'No matching records found !';
            %end
            
            function setFiltered(epoch, tf)
                epoch.filtered = tf;
            end
        end
        
        function splitEpochsByTags(app, conditions, splitMethod)
            switch splitMethod
                case 'Parallel'
                    % Split by each tag all available epochs
                    % (split by tag1 and tag2 the same source nodes, one level depth)
                    rows = size(conditions, 1);
                    for row = 1 : rows
                        tag    = conditions{row, 1}; % returns a cell with the property
                        tag    = tag{:};
                        
                        for i = 1:numel(app.UITree.SelectedNodes)
                            node = app.UITree.SelectedNodes(i);
                            nodeIdx = node.NodeData;
                            data = app.Tree.Node(nodeIdx);
                            data = data{1};
                            
                            cellData = app.findParentCellDataUITree(node);
                            if isa(data, 'EpochGroup')
                                mapByTagLevels = cellData.getEpochValuesMap(tag, data.epochIndices);
                            else
                                mapByTagLevels = cellData.getEpochValuesMap(tag); % [keys = unique tag levels, values = epoch indexes at that level]
                            end
                            
                            if isempty(mapByTagLevels)
                                continue
                            end
                            
                            keys = mapByTagLevels.keys;
                            for j=1:numel(keys)
                                group = EpochGroup(tag, keys{j});             % Find name of current tag
                                group.device = app.cmbDevices.Value;            % Process selected device
                                group.epochIndices = mapByTagLevels(keys{j});   % Store indexes of epochs sharing the same tag level
                                group.epochs = cellData.epochs(group.epochIndices);
                                group.populateEpochResponseAsFeature(cellData.epochs(group.epochIndices)); % Add epochs response to group
                                [app.Tree, newNodeIdx] = app.Tree.addnode(nodeIdx, group);
                                uitreenode(node,'Text',[tag ' = ' keys{j}],'NodeData', newNodeIdx, 'Icon', app.ICON_TAG);
                            end
                        end
                    end
                case 'Sequence'
                    % Split by each tag the epochs already segregated by the previous tag splitting event 
                    % (first by tag1 then each result further split by tag2, multi-level depth)
                    
                    % Accumulate all tags in a vector
                    rows = size(conditions, 1);
                    tags = {};
                    assignin('base','conditions', conditions);
                    for row = 1 : rows
                        % Extract the  cell from table
                        tag = conditions{row, 1}; 
                        if isempty(tags)
                            tags = tag;
                        else
                           tags(end+1) = tag; %#ok
                        end
                    end
                    
                    % Avoid using recursion in this step by visiting the tree breadth-first
                    for i = 1:numel(app.UITree.SelectedNodes)
                        oldNodes    = app.UITree.SelectedNodes(i);
                        oldNodesIdx = oldNodes.NodeData;                     
                        cellData    = app.findParentCellDataUITree(oldNodes);
                        
                        for j = 1:numel(tags)
                            tag = tags{j};
                            newNodes = [];
                            newNodesIdx = [];
                            
                            % Split every node that was created in the previous step                            
                            for n = i:numel(oldNodes)
                                node = oldNodes(n);
                                nodeIdx = oldNodesIdx(n);
                                data = app.Tree.Node(nodeIdx);
                                data = data{1};
                                
                                if isa(data, 'EpochGroup')
                                    mapByTagLevels = cellData.getEpochValuesMap(tag, data.epochIndices);
                                else
                                    mapByTagLevels = cellData.getEpochValuesMap(tag); % [keys = unique tag levels, values = epoch indexes at that level]
                                end
                                
                                if isempty(mapByTagLevels)
                                    continue
                                end
                                
                                keys = mapByTagLevels.keys;
                                for k=1:numel(keys)
                                    group = EpochGroup(tag, keys{k});             % Find name of current tag
                                    group.device = app.cmbDevices.Value;            % Process selected device
                                    group.epochIndices = mapByTagLevels(keys{k});   % Store indexes of epochs sharing the same tag level
                                    group.epochs = cellData.epochs(group.epochIndices);
                                    group.populateEpochResponseAsFeature(cellData.epochs(group.epochIndices)); % Add epochs response to group
                                    [app.Tree, newNodeIdx] = app.Tree.addnode(nodeIdx, group);
                                    newNode = uitreenode(node,'Text',[tag ' = ' keys{k}],'NodeData', newNodeIdx, 'Icon', app.ICON_TAG);
                                    
                                    if isempty(newNodes)
                                        newNodes = newNode;
                                        newNodesIdx = newNodeIdx;
                                    else
                                        newNodes(end+1) = newNode; %#ok
                                        newNodesIdx(end+1) = newNodeIdx; %#ok
                                    end
                                end
                            end
                            
                            oldNodes = newNodes;
                            oldNodesIdx = newNodesIdx;
                        end
                    end
                    
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Make sure there is a local folder in MATLAB's user path where to store settings
            if ~isfolder([userpath filesep 'Clarinet'])
                mkdir([userpath filesep 'Clarinet']);
                disp(['Creating a folder where all Clarinet settings will be stored: ' userpath filesep 'Clarinet']);
            end
            if ~isfolder([userpath filesep 'Clarinet' filesep '+customProcessors'])
                mkdir([userpath filesep 'Clarinet' filesep '+customProcessors']);
            end
            if ~isfolder([userpath filesep 'Clarinet' filesep '+customExtractors'])
                mkdir([userpath filesep 'Clarinet' filesep '+customExtractors']);
            end
            app.LocalFolder = [userpath filesep 'Clarinet'];
            addpath(app.LocalFolder);
            
            app.populateFilterList; % Load the available epoch processors
        end

        % Button pushed function: btnOpen
        function btnOpenButtonPushed(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    [fName, PathName] = uigetfile({'*.h5;*.mat','Supported formats (*.h5, *.mat)'; '*.h5','Symphony files (*.h5)';'*.mat','Clarinet files (*.mat)'}, 'Select experiments', 'MultiSelect', 'on');
                    app.Lamp.Color = 'Yellow';
                    app.Log.Value = 'Loading files...';
                    pause(0.2);
                    
                    app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                    app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
                    
                    if iscell(fName)
                        cd(PathName);
                        nbfiles = length(fName);
                    elseif fName ~= 0
                        cd(PathName);
                        nbfiles = 1;
                    else
                        nbfiles = 0;
                    end
                    
                    import parsers.*
                    dlg = uiprogressdlg(app.ClarinetUIFigure,'Title','Loading files...','Indeterminate','on');
                    if nbfiles == 1
                        [~,~,ext] = fileparts(fName);
                        
                        switch ext
                            case '.h5'
                                version = SymphonyParser.getVersion(fName);
                                if version == 2
                                    ref = SymphonyV2Parser(fName);
                                else
                                    ref = SymphonyV1Parser(fName);
                                end
                                ref.parse;
                                data = ref.getResult;
                                for j = 1:numel(data)
                                  if isempty(app.Cells)
                                      app.Cells = data{j};
                                  else
                                      app.Cells(end+1) = data{j};
                                  end
                                end  
                            case '.mat'
                                data = load(fName, 'Cells');
                                app.Cells = data.Cells;
                        end
                        
                        app.populateUITree;
                        cla(app.UIAxes);
                        
                        app.btnSave.Enable      = 'on';
                        app.btnExport.Enable    = 'off';
                        app.btnFind.Enable      = 'on';
                        app.btnBatchAdd.Enable  = 'off';
                        app.btnBatchSave.Enable = 'off';
                        app.btnBatchOpen.Enable = 'on';
                        app.btnBatchClear.Enable= 'on';
                        app.btnProcess.Enable   = 'off';
                        app.btnTagEmpty.Value   = true;
                        app.btnTreeRemove.Enable= 'off';
                        app.btnTreeClear.Enable = 'on';                        
                    elseif nbfiles >1
                        for i=1:numel(fName)
                            [~,~,ext] = fileparts(fName{i});
                            
                            switch ext
                                case '.h5'
                                    version = SymphonyParser.getVersion(fName{i});
                                    if version == 2
                                        ref = SymphonyV2Parser(fName{i});
                                    else
                                        ref = SymphonyV1Parser(fName{i});
                                    end
                                    ref.parse;
                                    data = ref.getResult;
                                    for j = 1:numel(data)
                                        if isempty(app.Cells)
                                            app.Cells = data{j};
                                        else
                                            app.Cells(end+1) = data{j};
                                        end
                                    end  
                                case '.mat'
                                    data = load(fName{i}, 'Cells');
                                    if isempty(app.Cells)
                                        app.Cells = data.Cells;
                                    else
                                        app.Cells(i) = data.Cells;
                                    end
                            end
                        end
                        
                        app.populateUITree;
                        cla(app.UIAxes);
                        
                        app.btnSave.Enable      = 'on';
                        app.btnExport.Enable    = 'off';
                        app.btnFind.Enable      = 'on';
                        app.btnBatchAdd.Enable  = 'off';
                        app.btnBatchSave.Enable = 'off';
                        app.btnBatchOpen.Enable = 'on';
                        app.btnBatchClear.Enable= 'on';
                        app.btnProcess.Enable   = 'off';
                        app.btnTagEmpty.Value   = true;
                        app.btnTreeRemove.Enable= 'off';
                        app.btnTreeClear.Enable = 'on';
                    else
                        app.btnSave.Enable      = 'off';
                        app.btnExport.Enable    = 'off';
                        app.btnFind.Enable      = 'off';
                        app.btnBatchAdd.Enable  = 'off';
                        app.btnBatchSave.Enable = 'off';
                        app.btnBatchOpen.Enable = 'off';
                        app.btnBatchClear.Enable= 'off';
                        app.btnProcess.Enable   = 'off';
                        app.btnTagEmpty.Value   = true;
                        app.btnTreeRemove.Enable= 'off';
                        app.btnTreeClear.Enable = 'off';
                    end
                    
                case 'Features'
                    [fName, PathName] = uigetfile({'*.mat','Clarinet experiments (*.mat)'}, 'Select experiments', 'MultiSelect', 'off');
                    app.Lamp.Color = 'Yellow';
                    app.Log.Value = 'Loading experiments...';
                    pause(0.2);
                    
                    app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                    app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
                    
                    if isempty(fName)
                        return
                    end

                    dlg = uiprogressdlg(app.ClarinetUIFigure,'Title','Loading files...','Indeterminate','on');                    
                    data = load(fullfile(PathName, fName), 'Tree');
                    app.Tree = data.Tree;
                    app.refreshUITree;
            end
            close(dlg);        
            app.Lamp.Color = 'Green';
            app.Log.Value = 'Loading experiments... DONE';
            
        end

        % Selection changed function: UITree
        function UITreeSelectionChanged(app, event)
            selectedNodes = app.UITree.SelectedNodes;
            switch app.Mode.Value
                case 'Epochs'                                        
                    if numel(selectedNodes) == 1
                        epoch = selectedNodes.NodeData;     % Retrieve epoch data
                        if isempty(epoch)
                            if ~isempty(app.Cells)   
                                app.tblMeta.Data = app.Cells.toTable;
                            end
                            app.cmbDevices.Items = {};
                            app.btnTagEmpty.Value = true;
                            return
                        end
                        if epoch.hasAttribute('tagColor')
                            switch epoch.attributes('tagColor')
                                case 'yellow',  app.btnTagYellow.Value  = true;
                                case 'green',   app.btnTagGreen.Value   = true;
                                case 'orange',  app.btnTagOrange.Value  = true;
                                case 'red',     app.btnTagRed.Value     = true;
                                case 'blue',    app.btnTagBlue.Value    = true;
                                case 'black',   app.btnTagBlack.Value   = true;
                                otherwise,      app.btnTagEmpty.Value   = true;
                            end
                        else
                            app.btnTagEmpty.Value = true;
                        end
                        if epoch.hasAttribute('pipeline')
                            pipe = epoch.attributes('pipeline');
                            tip = {['Epoch ' num2str(epoch.attributes('epochNum')) ' was processed with:']};
                            for i=1:numel(pipe)
                                tip(end+1) = {[num2str(i) ') ' pipe(i).name]}; %#ok
                            end
                            app.UITree.Tooltip    = tip;
                            app.btnBatchApply.Enable = 'on';
                        else
                            app.UITree.Tooltip    = '';
                            app.btnBatchApply.Enable = 'off';
                        end
                        app.cmbDevices.Items = epoch.get('devices'); % Populate available devices
                        app.tblMeta.Data = epoch.toTable;            % Populate metadata table
                        app.plotEpoch(epoch);                        % Plot epoch data on screen
                        
                        app.btnExport.Enable    = 'on';
                        app.btnBatchAdd.Enable  = 'on';
                        app.btnTagAdd.Enable    = 'on';
                        app.btnTagRemove.Enable = 'on';
                        app.btnTreeRemove.Enable= 'on';                        
                    elseif numel(selectedNodes) > 1
                        app.tblMeta.Data= [];
                        for i= numel(selectedNodes):-1:1
                            epochs(i) = selectedNodes(i).NodeData;     % Retrieve epoch data
                        end
                        app.cmbDevices.Items = epochs(1).get('devices'); % Populate available devices
                        app.plotEpoch(epochs);               % Plot epoch data on screen
                        
                        app.btnExport.Enable    = 'on';
                        app.btnBatchAdd.Enable  = 'on';
                        app.btnTagAdd.Enable    = 'on';
                        app.btnTagRemove.Enable = 'off';
                        app.btnTreeRemove.Enable= 'on';                        
                    else
                        app.btnExport.Enable    = 'off';
                        app.btnBatchAdd.Enable  = 'off';
                        app.btnProcess.Enable   = 'off';
                        app.btnTagAdd.Enable    = 'on';
                        app.btnTagRemove.Enable = 'off';
                        app.btnTreeRemove.Enable= 'off';                        
                    end
                case 'Features'
                    if numel(selectedNodes) == 1
                        data = app.Tree.Node(selectedNodes.NodeData);
                        data = data{1};
                        
                        switch class(data)
                            case 'Group'
                                % The root node was selected
                                app.btnFind.Enable                  = 'off';
                                app.btnProcess.Enable               = 'off';
                                app.btnBatchAdd.Enable              = 'off';
                                app.btnBatchOpen.Enable             = 'off';
                                app.btnTreeRemove.Enable            = 'off';
                                app.cmbSplitParam.Visible           = 'on';
                                app.btnExport.Enable                = 'on';
                                
                                % Gather information shared across cells
                                
                                % devices
                                devs = app.getDevicespAcrossCells; 
                                if ~isempty(devs)
                                    app.cmbDevices.Items = devs;
                                else
                                    app.cmbDevices.Items = {};
                                end
                                
                                % features
                                keys = app.getFeatureKeysAcrossCells; 
                                if ~isempty(keys)
                                    app.cmbFeatures.Items = keys;
                                else
                                    app.cmbFeatures.Items = {};
                                    app.tblMeta.Data = [];
                                end

                                % Split parameters
                                splitMap = app.getSplitStrategyMap;
                                if ~isempty(splitMap)
                                    splitValues = values(splitMap, splitMap.keys);
                                    app.cmbSplitParam.Items = splitMap.keys;
                                    app.tblMeta.Data = table(splitMap.keys', splitValues');
                                else
                                    app.cmbSplitParam.Items = {};
                                    app.tblMeta.Data = [];                                    
                                end
                                
                                app.plotAverage(app.cmbDevices.Value, app.cmbFeatures.Value, app.cmbSplitParam.Value);
                            case 'CellData'
                                % An experiment was selected
                                app.btnFind.Enable                  = 'on';
                                app.btnProcess.Enable               = 'off';
                                app.btnBatchAdd.Enable              = 'off';
                                app.btnBatchOpen.Enable             = 'off';
                                app.cmbSplitParam.Visible           = 'off';
                                app.btnExport.Enable                = 'on';
                                
                                app.cmbDevices.Items  = data.epochs(1).get('devices'); % Populate available devices
                                [params, vals]        = data.getParamValues;
                                app.tblMeta.Data      = table(params', vals');  % Populate metadata table                                
                                app.cmbFeatures.Items = params;                                
                                    app.cmbFeaturesValueChanged;
                            case 'EpochGroup'
                                % An EpochGroup was selected
                                app.btnFind.Enable                  = 'on';
                                app.btnProcess.Enable               = 'on';
                                app.btnBatchAdd.Enable              = 'on';
                                app.btnBatchOpen.Enable             = 'on';
                                app.btnTreeRemove.Enable            = 'on';
                                app.cmbSplitParam.Visible           = 'off';
                                app.btnExport.Enable                = 'on';
                                
                                app.cmbFeatures.Items = data.getFeatureKey;
                                keys = data.getFeatureKey;
                                for i=numel(keys):-1:1
                                    vals{i} = data.getFeatureData(keys{i});
                                end
                                app.tblMeta.Data = table(keys',vals');
                                app.cmbFeaturesValueChanged;
                        end
                    elseif numel(selectedNodes) > 1
                        % Retrieve data from app.Tree
                        
                        for i = numel(selectedNodes):-1:1
                            nodeIdx = selectedNodes(i).NodeData;
                            newData = app.Tree.Node(nodeIdx);
                            data{i} = newData{1};
                        end
                        
                        assignin('base','data', data);
                        if iseach(data, 'CellData')
                            CellDatas = [data{:}]; % Convert cell array to a homogenerous array
                            % All selected objects are CellData
                            app.btnFind.Enable                  = 'on';
                            app.btnProcess.Enable               = 'off';
                            app.btnBatchAdd.Enable              = 'off';
                            app.btnBatchOpen.Enable             = 'off';
                            app.btnTreeRemove.Enable            = 'off';
                            app.cmbSplitParam.Visible           = 'off';
                            app.btnExport.Enable                = 'off';
                            
                            app.cmbDevices.Items = CellDatas(1).epochs(1).get('devices'); % Populate available devices
                            app.cmbFeatures.Items = {};
                            [params, vals] = CellDatas(1).getParamValues;
                            app.tblMeta.Data = table(params', vals');  % Populate metadata table
                            
                            cla(app.UIAxes,'reset');
                            app.UIAxes.XGrid                    = true;
                            app.UIAxes.YGrid                    = true;
                            app.UIAxes.ZGrid                    = true;
                        elseif iseach(data, 'EpochGroup')
                            EpochGroups = [data{:}]; % Convert cell array to a homogenerous array
                            % All selected objects are EpochGroup
                            app.btnFind.Enable                  = 'on';
                            app.btnProcess.Enable               = 'on';
                            app.btnBatchAdd.Enable              = 'on';
                            app.btnBatchOpen.Enable             = 'off';
                            app.btnTreeRemove.Enable            = 'off';
                            app.cmbSplitParam.Visible           = 'off';
                            app.btnExport.Enable                = 'off';
                            
                            app.cmbDevices.Items = {EpochGroups.device}; % Populate available devices
                            keys = app.getFeatureKeysAcrossEpochGroups(selectedNodes);
                            app.cmbFeatures.Items = keys;
                            for i=numel(keys):-1:1
                                for j = 1:numel(EpochGroups)
                                    if j==1
                                        vals{i} = EpochGroups(j).getFeatureData(keys{i});
                                    else
                                        v = vals{1};
                                        v{end+1} = EpochGroups(j).getFeatureData(keys{i});
                                        vals{i} = v;
                                    end
                                end
                            end
                            app.tblMeta.Data = table(keys', vals');  % Populate metadata table
                            
                            cla(app.UIAxes,'reset');
                            app.UIAxes.XGrid                    = true;
                            app.UIAxes.YGrid                    = true;
                            app.UIAxes.ZGrid                    = true;                            
                        else
                            % Different types of objects were selected
                            app.btnFind.Enable                  = 'off';
                            app.btnProcess.Enable               = 'off';
                            app.btnBatchAdd.Enable              = 'off';
                            app.btnBatchOpen.Enable             = 'off';
                            app.btnTreeRemove.Enable            = 'off';
                            app.cmbSplitParam.Visible           = 'off';
                            app.btnExport.Enable                = 'off';
                            
                            app.cmbDevices.Items                = {};
                            app.cmbFeatures.Items               = {};
                            app.tblMeta.Data                    = [];
                            
                            cla(app.UIAxes,'reset');
                            app.UIAxes.XGrid                    = true;
                            app.UIAxes.YGrid                    = true;
                            app.UIAxes.ZGrid                    = true;
                        end
                    end
            end
        end

        % Button pushed function: btnExport
        function btnExportButtonPushed(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    selectedNodes = app.UITree.SelectedNodes;
                    
                    if numel(selectedNodes) >= 1
                        for i= numel(selectedNodes):-1:1
                            epoch = selectedNodes(i).NodeData;
                            meta = epoch.toStructure;
                            
                            % Filter the raw response if no previous filter was applied or user selected 'Raw'
                            switch app.btnResponseType.Value
                                case 'Raw'
                                    response = epoch.getResponse(app.cmbDevices.Value);
                                case 'Processed'
                                    if epoch.hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                                        response = epoch.getDerivedResponse('filteredResponse', app.cmbDevices.Value);
                                    else
                                        response = epoch.getResponse(app.cmbDevices.Value); % Use raw response if no filtered is available
                                    end
                            end
                            
                            epochs(i).epoch = response.quantity'; % Retrieve epoch data
                            epochs(i).meta  = meta;               % Retrieve epoch metadata
                        end
                        uisave({'epochs'},'SelectedEpochs.mat');
                        
                        app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                        app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
                    end
                case 'Features'
                    % Create a temporary figure with axes (UIAxes cannot save directly to file)
                    fig = figure('Visible', 'off');
                    figAxes = axes(fig);
                    % Copy all UIAxes children, take over axes limits and aspect ratio.            
                    allChildren = app.UIAxes.XAxis.Parent.Children;
                    copyobj(allChildren, figAxes);
                    figAxes.XLim = app.UIAxes.XLim;
                    figAxes.YLim = app.UIAxes.YLim;
                    figAxes.ZLim = app.UIAxes.ZLim;
                    figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;

                    [fileName,pathName] = uiputfile({'*.pdf', 'Portable Document File (*.pdf)';...
                                                     '*.svg','Scalable Vector Graphic (*.svg)';...
                                                     '*.png','Potable network Graphic (*.png)';...
                                                     '*.tif', 'TIFF image, uncompressed (*.tif)'});
                    [~,~,ext] = fileparts(fileName);
                    switch ext
                        case '.pdf'
                            saveas(fig, fullfile(pathName,fileName), 'pdf');
                        case '.svg'
                            saveas(fig, fullfile(pathName,fileName), 'svg');
                        case '.png'
                            saveas(fig, fullfile(pathName,fileName), 'png');                            
                        case '.tif'
                            saveas(fig, fullfile(pathName,fileName), 'tiffn');  
                    end
                    % Delete the temporary figure.
                    delete(fig);                    
            end
        end

        % Button pushed function: btnAbout
        function btnAboutButtonPushed(app, event)
            if isempty(app.dlgAbout)  || ~isprop(app.dlgAbout,'UIFigure')
                app.dlgAbout = DialogAbout;

                % Center the dialog on main window
                pos = app.dlgAbout.UIFigure.Position;
                posClarinet = app.ClarinetUIFigure.Position;
                pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
                pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
                app.dlgAbout.UIFigure.Position = pos;
            end
            app.dlgAbout.UIFigure.Visible  = 'on';
        end

        % Value changed function: cmbProcessors
        function cmbProcessorsValueChanged(app, event)
            switch app.Mode.Value
                case 'Epochs'                    
                    import processors.*
                    import customProcessors.*        
                case 'Features'
                    import extractors.*
            end
            % Restrieve default settings
            filterName = app.cmbProcessors.Value;
            settings = feval(filterName);
            
            if numel(fieldnames(settings)) > 0
                app.btnSettings.Enable = 'on';
            else
                app.btnSettings.Enable = 'off';
            end
            
            app.cmbProcessors.Tooltip = help(filterName);
            app.btnSettings.UserData = settings;                    
        end

        % Button pushed function: btnProcess
        function btnProcessButtonPushed(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    import processors.*
                    import customProcessors.*
                    
                    if isempty(app.pipeline)
                        app.btnBatchAddButtonPushed; % Add the current processo and use just that one
                    end
                    
                    selectedNodes = app.UITree.SelectedNodes;
                    for i=1:numel(selectedNodes)
                        % Use raw response if no previous filter was applied or user pressed the 'Raw' switch
                        epoch   = selectedNodes(i).NodeData;
                        if strcmp(app.btnResponseType.Value,'Raw') || ~epoch.hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                            response = epoch.getResponse(app.cmbDevices.Value);
                            epoch.addDerivedResponse('filteredResponse', response, app.cmbDevices.Value);
                        end
                        
                        % Apply all processors in the pipeline to the epoch
                        for j = 1:numel(app.pipeline)
                            proc    = app.pipeline(j);
                            epoch   = feval(proc.name, epoch, app.cmbDevices.Value, proc.settings);
                        end
                        epoch.attributes('pipeline') = app.pipeline;
                        selectedNodes(i).NodeData = epoch;
                    end
                    
                    
                    app.btnResponseType.Value = 'Processed';
                    app.btnResponseTypeValueChanged;
                    app.UITreeSelectionChanged;
                case 'Features'
                    import extractors.*
                    
                    % Apply all extractors in the pipeline to each selected EpochGroup
                    for i = 1:numel(app.UITree.SelectedNodes)
                        nodeIdx = app.UITree.SelectedNodes(i).NodeData;
                        data = app.Tree.Node(nodeIdx);
                        data = data{1};
                        if ~isa(data, 'EpochGroup')
                            continue
                        end
                        
                        for j = 1:numel(app.pipeline)
                            proc    = app.pipeline(j);
                            newData = feval(proc.name, data, app.cmbDevices.Value, proc.settings);
                        end
                        app.Tree.set(nodeIdx, newData);
                    end
                    app.UITreeSelectionChanged;
            end
        end

        % Button pushed function: btnSettings
        function btnSettingsButtonPushed(app, event)
            settings = app.btnSettings.UserData;
            
            newSettings = editStruct(settings);
            if ~isempty(newSettings)
                app.btnSettings.UserData = newSettings;
            end
        end

        % Button pushed function: btnFind
        function btnFindButtonPushed(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    if isempty(app.dlgFind) || ~isprop(app.dlgFind,'UIFigure')
                        app.dlgFind = DialogFind(app);
                        
                        % Center the dialog on main window
                        pos = app.dlgFind.UIFigure.Position;
                        posClarinet = app.ClarinetUIFigure.Position;
                        pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
                        pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
                        app.dlgFind.UIFigure.Position = pos;
                    else
                        app.dlgFind.UIFigure.Visible = 'on';
                    end
                case 'Features'
                    if isempty(app.dlgGroupBy) || ~isprop(app.dlgGroupBy,'UIFigure')
                        app.dlgGroupBy = DialogGroupBy(app);
                    else
                        app.dlgGroupBy.UIFigure.Visible = 'on';
                    end
                    % Center the dialog on main window
                    pos = app.dlgGroupBy.UIFigure.Position;
                    posClarinet = app.ClarinetUIFigure.Position;
                    pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
                    pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
                    app.dlgGroupBy.UIFigure.Position = pos;
                                        
                    % Populate combo box with available tags
                    nodes = app.UITree.SelectedNodes;
                    if numel(nodes) == 1
                        parentCell = app.findParentCellDataUITree(nodes);
                        [params, ~] = parentCell.getParamValues;
                        tags = params;
                    elseif numel(nodes) >1
                        for i = 1:numel(nodes)
                            parentCell = app.findParentCellDataUITree(nodes(i));
                            [params, ~] = parentCell.getParamValues;
                            if i==1
                                tags = params;
                            else
                                tags = union(tags, params);
                            end
                        end
                    end
                    app.dlgGroupBy.cmbTags.Items = sort(tags);                    
            end
        end

        % Value changed function: btnResponseType
        function btnResponseTypeValueChanged(app, event)
            switch app.btnResponseType.Value
                case 'Raw'
                    app.btnResponseType.FontColor = [0 0 0];
                case 'Processed'
                    app.btnResponseType.FontColor = [0.85 0.33 0.10];
            end
            app.UITreeSelectionChanged;
        end

        % Value changed function: cmbDevices
        function cmbDevicesValueChanged(app, event)
            app.UITreeSelectionChanged;
        end

        % Value changed function: XScaleType
        function XScaleTypeValueChanged(app, event)
            app.UITreeSelectionChanged;
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    % Synch current cell with updated data from EpochTree nodes
                    cellNodes = app.rootNodeCells.Children;
                    for i = 1 : numel(cellNodes)
                        node = cellNodes(i);
                        epochNodes = node.Children;
                        for j = 1:numel(epochNodes)
                            app.Cells(i).epochs(j) = epochNodes(j).NodeData;
                        end
                    end
                    
                    % Save cell on disk
                    Cells = app.Cells; %#ok
                    uisave({'Cells'},[app.Cells(1).h5File '.mat']);
                    
                    app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                    app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
                case 'Features'
                    % Save the analysis tree on disk
                    Tree = app.Tree; %#ok
                    uisave({'Tree'},['Features.mat']);
                    
                    app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                    app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            end
        end

        % Value changed function: btnEpochTypes
        function btnEpochTypesValueChanged(app, event)
            switch app.btnEpochTypes.Value
                case 'All'
                    app.btnEpochTypes.FontColor = [0 0 0];
                    if ~isempty(app.Cells)
                        app.populateUITree;
                    end
                case 'Searched'
                    app.btnEpochTypes.FontColor = [0.85 0.33 0.10];
                    if ~isempty(app.Cells)
                        app.populateUITree;
                    end
            end
        end

        % Close request function: ClarinetUIFigure
        function ClarinetUIFigureCloseRequest(app, event)
            delete(app.dlgAbout)
            delete(app.dlgFind)
            delete(app)
        end

        % Button pushed function: btnBatchAdd
        function btnBatchAddButtonPushed(app, event)
            % Append selected processor to the pipeline
            processor.name              = app.cmbProcessors.Value;
            processor.settings          = app.btnSettings.UserData;
            if isempty(app.pipeline)
                app.pipeline            = processor;
            else
                app.pipeline(end+1)     = processor;
            end
            
            tip = {[app.btnProcess.Text ' selected epochs with:'], ''};
            for i=1:numel(app.pipeline)
                tip(end+1) = {[num2str(i) ') ' app.pipeline(i).name]}; %#ok
            end
            app.btnProcess.Tooltip      = tip;
            app.btnBatchSave.Enable     = 'on';
            app.btnBatchClear.Enable    = 'on';
            app.btnProcess.Enable       = 'on';
        end

        % Button pushed function: btnBatchClear
        function btnBatchClearButtonPushed(app, event)
            app.pipeline = [];
            app.btnProcess.Tooltip      = {[app.btnProcess.Text ' selected epochs']};
            app.btnBatchSave.Enable     = 'off';
            app.btnProcess.Enable       = 'off';
            app.btnBatchClear.Enable    = 'off';
        end

        % Selection changed function: pnlEpochTools
        function pnlEpochToolsSelectionChanged(app, event)
            selectedButton = app.pnlEpochTools.SelectedObject;
            for i=1:numel(app.UITree.SelectedNodes)
                if strcmp(selectedButton.Icon, 'white.png')
                    app.UITree.SelectedNodes(i).Icon = '';
                    app.UITree.SelectedNodes(i).NodeData.attributes('tagColor') = '';
                else
                    app.UITree.SelectedNodes(i).Icon = selectedButton.Icon;
                    [~,colorName,~] = fileparts(selectedButton.Icon);
                    app.UITree.SelectedNodes(i).NodeData.attributes('tagColor') = colorName;
                end
            end
        end

        % Button pushed function: btnBatchOpen
        function btnBatchOpenButtonPushed(app, event)
            [fName, PathName] = uigetfile({'*.mat','Clarinet pipelines (*.mat)'}, 'Select experiments', 'MultiSelect', 'off');
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            data = load(fullfile(PathName, fName), 'pipeline');
            app.pipeline = data.pipeline;
            
            tip = {'Add to processing pipeline', '', 'Current pipeline:'};
            for i=1:numel(app.pipeline)
                tip(end+1) = {[num2str(i) ') ' app.pipeline(i).name]}; %#ok
            end
            app.btnBatchAdd.Tooltip = tip;
            
            app.btnBatchAdd.Enable   = 'on';
            app.btnBatchClear.Enable = 'on';
            app.btnBatchSave.Enable  = 'on';
            app.btnProcess.Enable    = 'on';
        end

        % Button pushed function: btnBatchSave
        function btnBatchSaveButtonPushed(app, event)
            % Save processing pipeline on disk
            pipeline = app.pipeline; %#ok
            uisave({'pipeline'},'pipeline.mat');
            
            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
        end

        % Button pushed function: btnTagAdd
        function btnTagAddButtonPushed(app, event)
            Tag.Name = 'CustomTag';
            Tag.Value = 'true';
            
            newTag = editStruct(Tag, 'Add Tag');
            if ~isempty(newTag)
                % try to convert the value to number, if not possible store it as string
                value = str2double(newTag.Value);
                if ~isnan(value)
                    newTag.Value = value;
                end
                
                % Add the new Tag/Value pair to the list of attributes for selected epochs
                for i=1:numel(app.UITree.SelectedNodes)
                    app.UITree.SelectedNodes(i).NodeData.attributes(newTag.Name) = newTag.Value;
                end
                app.UITreeSelectionChanged;
            end
        end

        % Button pushed function: btnTagRemove
        function btnTagRemoveButtonPushed(app, event)
            if ~isempty(app.selectedTagRows)
                Tag = app.tblMeta.Data{app.selectedTagRows(:,1),1};
                Tag = Tag{:};
                for i=1:numel(app.UITree.SelectedNodes)
                    remove(app.UITree.SelectedNodes(i).NodeData.attributes, Tag);
                end
                app.UITreeSelectionChanged;
            end            
        end

        % Cell selection callback: tblMeta
        function tblMetaCellSelection(app, event)
            indices = event.Indices;
            app.selectedTagRows = indices;
        end

        % Button pushed function: btnBatchApply
        function btnBatchApplyButtonPushed(app, event)
            selectedNodes = app.UITree.SelectedNodes;
            % Load processing pipeline from selected epoch            
            if numel(selectedNodes) == 1
                epoch = selectedNodes.NodeData;     % Retrieve epoch data
                if epoch.hasAttribute('pipeline')
                    app.pipeline = epoch.attributes('pipeline');

                    tip = {'Process selected epochs with:', ''};
                    for i=1:numel(app.pipeline)
                        tip(end+1) = {[num2str(i) ') ' app.pipeline(i).name]}; %#ok
                    end
                    app.btnProcess.Tooltip = tip;
                    
                    app.btnBatchAdd.Enable   = 'on';
                    app.btnBatchClear.Enable = 'on';
                    app.btnBatchSave.Enable  = 'on';
                    app.btnProcess.Enable    = 'on';                    
                end
            end            
        end

        % Button pushed function: btnTreeRemove
        function btnTreeRemoveButtonPushed(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    delete(app.UITree.SelectedNodes);
                case 'Features'
                    nodeIdx = [app.UITree.SelectedNodes.NodeData];
                    app.Tree = app.Tree.chop(nodeIdx);
                    app.refreshUITree;
            end        
        end

        % Button pushed function: btnTreeClear
        function btnTreeClearButtonPushed(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    app.Cells = [];
                    app.populateUITree;
                case 'Features'
                    app.populateUITree;
            end
        end

        % Value changed function: Mode
        function ModeValueChanged(app, event)
            % Change toolbar buttons behavior depending of the mode in which Clarinet is bein used
            switch app.Mode.Value
                case 'Epochs'
                    app.Mode.FontColor          = [0 0 0];
                    app.lblProcessors.Text      = 'Epoch processors';
                    app.btnOpen.Tooltip         = 'Load epochs from file';
                    app.btnSave.Tooltip         = 'Save current epochs to file';
                    app.btnSettings.Tooltip     = 'Edit settings of selected processor';
                    app.btnProcess.Tooltip      = 'Process selected epochs';
                    app.UITree.Multiselect      = 'on';
                    app.pnlFeatureTools.Visible = 'off';
                    app.pnlEpochTools.Visible   = 'on';
                    app.btnProcess.Text         = 'Process';
                    app.btnFind.Text            = 'Search';
                    app.btnFind.Icon            = '48px-search.png';
                    app.btnFind.Tooltip         = 'Select epoch meeting specific conditions';
                    app.btnFind.Enable          = 'on';
                    app.populateUITree;
                case 'Features'
                    app.Mode.FontColor          = [0.85 0.33 0.10];
                    app.lblProcessors.Text      = 'Feature extractors';
                    app.btnOpen.Tooltip         = 'Load analysis tree from file';
                    app.btnSave.Tooltip         = 'Save current analysis tree to file';
                    app.btnSettings.Tooltip     = 'Edit settings of selected extractor';
                    app.btnProcess.Tooltip      = 'Extract selected features';
                    app.UITree.Multiselect      = 'on';
                    app.pnlFeatureTools.Visible = 'on';
                    app.pnlEpochTools.Visible   = 'off';
                    app.btnProcess.Text         = 'Extract';
                    app.btnFind.Text            = 'Split';
                    app.btnFind.Icon            = '48px-split.png';
                    app.btnFind.Tooltip         = 'Split epochs by tag value';
                    app.btnFind.Enable          = 'off';
                    if isempty(app.Tree) || app.Tree.nnodes<2
                        app.populateUITree;
                    else
                        app.refreshUITree;
                    end
            end
            app.populateFilterList;
        end

        % Value changed function: cmbFeatures
        function cmbFeaturesValueChanged(app, event)
            if ~isempty(app.cmbFeatures.Value)
                nodeIdx = app.UITree.SelectedNodes.NodeData;
                data = app.Tree.Node(nodeIdx);
                data = data{1};
                switch class(data)
                    case 'Group'
                        app.plotAverage(app.cmbDevices.Value, app.cmbFeatures.Value, app.cmbSplitParam.Value);
                    case 'CellData'
                        app.plotAttribute(data, app.cmbFeatures.Value);
                    case 'EpochGroup'
                        app.plotFeature(data, app.cmbFeatures.Value);
                end
            end
        end

        % Selection changed function: btnGroupVisualizationType
        function btnGroupVisualizationTypeSelectionChanged(app, event)
            switch app.Mode.Value
                case 'Epochs'
                    app.plotEpoch([app.UITree.SelectedNodes.NodeData]);
                case 'Features'
                    if numel(app.UITree.SelectedNodes) == 1
                        data = app.Tree.Node(app.UITree.SelectedNodes.NodeData);
                        data = data{1};
                        
                        switch class(data)
                            case 'Group'
                                app.plotAverage(app.cmbDevices.Value, app.cmbFeatures.Value, app.cmbSplitParam.Value);
                            case 'CellData'
                                app.plotAttribute(data, app.cmbFeatures.Value);
                            case 'EpochGroup'
                                app.plotFeature(data, app.cmbFeatures.Value);
                        end
                    end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClarinetUIFigure = uifigure;
            app.ClarinetUIFigure.Name = 'Clarinet';
            app.ClarinetUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClarinetUIFigureCloseRequest, true);
            app.Lamp = uilamp(app.ClarinetUIFigure);
            app.Lamp.Tooltip = {'App status:'; 'Green = OK'; 'Yellow = Busy'; 'Red = Error'};
            app.Log = uieditfield(app.ClarinetUIFigure, 'text');
            app.Log.Tooltip = {'Status bar keeping track of last event occurred'};
            app.Log.Value = 'Welcome to Clarinet, open an experiment to begin';
            app.UIAxes = uiaxes(app.ClarinetUIFigure);
            app.pnlToolbar = uipanel(app.ClarinetUIFigure);
            app.btnFind = uibutton(app.pnlToolbar, 'push');
            app.btnFind.ButtonPushedFcn = createCallbackFcn(app, @btnFindButtonPushed, true);
            app.btnFind.Icon = '48px-search.png';
            app.btnFind.Enable = 'off';
            app.btnFind.Tooltip = {'Select epochs meeting specific conditions'};
            app.btnFind.Text = 'Search';
            app.btnOpen = uibutton(app.pnlToolbar, 'push');
            app.btnOpen.ButtonPushedFcn = createCallbackFcn(app, @btnOpenButtonPushed, true);
            app.btnOpen.Icon = '48px-open.png';
            app.btnOpen.Tooltip = {'Load epochs from an experiment'};
            app.btnOpen.Text = 'Open';
            app.btnAbout = uibutton(app.pnlToolbar, 'push');
            app.btnAbout.ButtonPushedFcn = createCallbackFcn(app, @btnAboutButtonPushed, true);
            app.btnAbout.Icon = '48px-info.png';
            app.btnAbout.Tooltip = {'Information about Clarinet'};
            app.btnAbout.Text = 'About';
            app.btnExport = uibutton(app.pnlToolbar, 'push');
            app.btnExport.ButtonPushedFcn = createCallbackFcn(app, @btnExportButtonPushed, true);
            app.btnExport.Icon = '48px-export.png';
            app.btnExport.Enable = 'off';
            app.btnExport.Tooltip = {'Export selected epochs data to .mat file'};
            app.btnExport.Text = {'Export'; ''};
            app.btnSave = uibutton(app.pnlToolbar, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Icon = '48px-save.png';
            app.btnSave.Enable = 'off';
            app.btnSave.Tooltip = {'Save the experiment into .mat file'};
            app.btnSave.Text = 'Save';
            app.btnProcess = uibutton(app.pnlToolbar, 'push');
            app.btnProcess.ButtonPushedFcn = createCallbackFcn(app, @btnProcessButtonPushed, true);
            app.btnProcess.Icon = '48px-execute.png';
            app.btnProcess.Enable = 'off';
            app.btnProcess.Tooltip = {'Process selected epochs'};
            app.btnProcess.Text = 'Process';
            app.btnSettings = uibutton(app.pnlToolbar, 'push');
            app.btnSettings.ButtonPushedFcn = createCallbackFcn(app, @btnSettingsButtonPushed, true);
            app.btnSettings.Icon = '48px-settings.png';
            app.btnSettings.Tooltip = {'Edit settings for chosen filter'};
            app.btnSettings.Text = 'Settings';
            app.cmbProcessors = uidropdown(app.pnlToolbar);
            app.cmbProcessors.Items = {};
            app.cmbProcessors.ValueChangedFcn = createCallbackFcn(app, @cmbProcessorsValueChanged, true);
            app.cmbProcessors.Value = {};
            app.btnBatchAdd = uibutton(app.pnlToolbar, 'push');
            app.btnBatchAdd.ButtonPushedFcn = createCallbackFcn(app, @btnBatchAddButtonPushed, true);
            app.btnBatchAdd.Icon = '48px-List-add.png';
            app.btnBatchAdd.Enable = 'off';
            app.btnBatchAdd.Tooltip = {'Add processor to pipeline'};
            app.btnBatchAdd.Text = 'Add to Pipeline';
            app.btnBatchClear = uibutton(app.pnlToolbar, 'push');
            app.btnBatchClear.ButtonPushedFcn = createCallbackFcn(app, @btnBatchClearButtonPushed, true);
            app.btnBatchClear.Icon = '48px-delete.png';
            app.btnBatchClear.Enable = 'off';
            app.btnBatchClear.Tooltip = {'Clear processing pipeline'};
            app.btnBatchClear.Text = '';
            app.lblProcessors = uilabel(app.pnlToolbar);
            app.lblProcessors.Text = 'Epoch processors';
            app.btnBatchOpen = uibutton(app.pnlToolbar, 'push');
            app.btnBatchOpen.ButtonPushedFcn = createCallbackFcn(app, @btnBatchOpenButtonPushed, true);
            app.btnBatchOpen.Icon = '48px-open.png';
            app.btnBatchOpen.Enable = 'off';
            app.btnBatchOpen.Tooltip = {'Load a previously saved processing pipeline'};
            app.btnBatchOpen.Text = '';
            app.btnBatchSave = uibutton(app.pnlToolbar, 'push');
            app.btnBatchSave.ButtonPushedFcn = createCallbackFcn(app, @btnBatchSaveButtonPushed, true);
            app.btnBatchSave.Icon = '48px-save.png';
            app.btnBatchSave.Enable = 'off';
            app.btnBatchSave.Tooltip = {'Sace current pipeline'};
            app.btnBatchSave.Text = '';
            app.btnBatchApply = uibutton(app.pnlToolbar, 'push');
            app.btnBatchApply.ButtonPushedFcn = createCallbackFcn(app, @btnBatchApplyButtonPushed, true);
            app.btnBatchApply.Icon = '48px-pipeline-apply.png';
            app.btnBatchApply.Enable = 'off';
            app.btnBatchApply.Tooltip = {'Load processing pipeline used for selected epoch'};
            app.btnBatchApply.Text = '';
            app.btnTreeRemove = uibutton(app.pnlToolbar, 'push');
            app.btnTreeRemove.ButtonPushedFcn = createCallbackFcn(app, @btnTreeRemoveButtonPushed, true);
            app.btnTreeRemove.Icon = '48px-List-remove.png';
            app.btnTreeRemove.Enable = 'off';
            app.btnTreeRemove.Tooltip = {'Remve selected element from the tree'};
            app.btnTreeRemove.Text = '';
            app.btnTreeClear = uibutton(app.pnlToolbar, 'push');
            app.btnTreeClear.ButtonPushedFcn = createCallbackFcn(app, @btnTreeClearButtonPushed, true);
            app.btnTreeClear.Icon = '48px-list-clear.png';
            app.btnTreeClear.Enable = 'off';
            app.btnTreeClear.Tooltip = {'Clear the tree'};
            app.btnTreeClear.Text = '';
            app.XScaleType = uiswitch(app.ClarinetUIFigure, 'slider');
            app.XScaleType.Items = {'Time (s)', 'Samples'};
            app.XScaleType.ValueChangedFcn = createCallbackFcn(app, @XScaleTypeValueChanged, true);
            app.XScaleType.Tooltip = {'Switch the abscissa scale'};
            app.XScaleType.Value = 'Time (s)';
            app.btnGroupVisualizationType = uibuttongroup(app.ClarinetUIFigure);
            app.btnGroupVisualizationType.SelectionChangedFcn = createCallbackFcn(app, @btnGroupVisualizationTypeSelectionChanged, true);
            app.btnPlot2D = uitogglebutton(app.btnGroupVisualizationType);
            app.btnPlot2D.Text = '2D';
            app.btnPlot2D.Value = true;
            app.btnPlot3D = uitogglebutton(app.btnGroupVisualizationType);
            app.btnPlot3D.Text = '3D';
            app.UITree = uitree(app.ClarinetUIFigure);
            app.UITree.Multiselect = 'on';
            app.UITree.SelectionChangedFcn = createCallbackFcn(app, @UITreeSelectionChanged, true);
            app.pnlEpochTools = uibuttongroup(app.ClarinetUIFigure);
            app.pnlEpochTools.SelectionChangedFcn = createCallbackFcn(app, @pnlEpochToolsSelectionChanged, true);
            app.pnlEpochTools.Tooltip = {'Color Tag selected epochs'};
            app.btnTagEmpty = uitogglebutton(app.pnlEpochTools);
            app.btnTagEmpty.Icon = 'white.png';
            app.btnTagEmpty.Text = '';
            app.btnTagEmpty.Value = true;
            app.btnTagYellow = uitogglebutton(app.pnlEpochTools);
            app.btnTagYellow.Icon = 'yellow.png';
            app.btnTagYellow.Text = '';
            app.btnTagGreen = uitogglebutton(app.pnlEpochTools);
            app.btnTagGreen.Icon = 'green.png';
            app.btnTagGreen.Text = '';
            app.btnTagOrange = uitogglebutton(app.pnlEpochTools);
            app.btnTagOrange.Icon = 'orange.png';
            app.btnTagOrange.Text = '';
            app.btnTagBlue = uitogglebutton(app.pnlEpochTools);
            app.btnTagBlue.Icon = 'blue.png';
            app.btnTagBlue.Text = '';
            app.btnTagRed = uitogglebutton(app.pnlEpochTools);
            app.btnTagRed.Icon = 'red.png';
            app.btnTagRed.Text = '';
            app.btnTagBlack = uitogglebutton(app.pnlEpochTools);
            app.btnTagBlack.Icon = 'black.png';
            app.btnTagBlack.Text = '';
            app.btnEpochTypes = uiswitch(app.pnlEpochTools, 'slider');
            app.btnEpochTypes.Items = {'All', 'Searched'};
            app.btnEpochTypes.ValueChangedFcn = createCallbackFcn(app, @btnEpochTypesValueChanged, true);
            app.btnEpochTypes.Tooltip = {'Epochs to display'};
            app.btnEpochTypes.Value = 'All';
            app.btnTagRemove = uibutton(app.pnlEpochTools, 'push');
            app.btnTagRemove.ButtonPushedFcn = createCallbackFcn(app, @btnTagRemoveButtonPushed, true);
            app.btnTagRemove.Icon = '48px-tag-Remove.png';
            app.btnTagRemove.Enable = 'off';
            app.btnTagRemove.Tooltip = {'Remve selected tag from the list'};
            app.btnTagRemove.Text = '';
            app.btnTagAdd = uibutton(app.pnlEpochTools, 'push');
            app.btnTagAdd.ButtonPushedFcn = createCallbackFcn(app, @btnTagAddButtonPushed, true);
            app.btnTagAdd.Icon = '48px-tag-add.png';
            app.btnTagAdd.Enable = 'off';
            app.btnTagAdd.Tooltip = {'Add a custom tag to selected epochs'};
            app.btnTagAdd.Text = '';
            app.btnResponseType = uiswitch(app.pnlEpochTools, 'slider');
            app.btnResponseType.Items = {'Raw', 'Processed'};
            app.btnResponseType.ValueChangedFcn = createCallbackFcn(app, @btnResponseTypeValueChanged, true);
            app.btnResponseType.Tooltip = {'Epoch version to process'};
            app.btnResponseType.Value = 'Raw';
            app.tblMeta = uitable(app.ClarinetUIFigure);
            app.tblMeta.ColumnName = {'Tag'; 'Value'};
            app.tblMeta.RowName = {};
            app.tblMeta.CellSelectionCallback = createCallbackFcn(app, @tblMetaCellSelection, true);
            app.Mode = uiswitch(app.ClarinetUIFigure, 'slider');
            app.Mode.Items = {'Epochs', 'Features'};
            app.Mode.ValueChangedFcn = createCallbackFcn(app, @ModeValueChanged, true);
            app.Mode.Value = 'Epochs';
            app.pnlFeatureTools = uipanel(app.ClarinetUIFigure);
            app.pnlFeatureTools.Visible = 'off';
            app.cmbFeatures = uidropdown(app.pnlFeatureTools);
            app.cmbFeatures.Items = {};
            app.cmbFeatures.ValueChangedFcn = createCallbackFcn(app, @cmbFeaturesValueChanged, true);
            app.cmbFeatures.Tooltip = {'Features available for selected items'};
            app.cmbFeatures.Value = {};
            app.FeatureLabel = uilabel(app.pnlFeatureTools);
            app.FeatureLabel.Text = 'Feature:';
            app.cmbSplitParam = uidropdown(app.pnlFeatureTools);
            app.cmbSplitParam.Items = {};
            app.cmbSplitParam.Tooltip = {'Split Features by tag'};
            app.cmbSplitParam.Value = {};
            app.cmbDevices = uidropdown(app.ClarinetUIFigure);
            app.cmbDevices.Items = {};
            app.cmbDevices.ValueChangedFcn = createCallbackFcn(app, @cmbDevicesValueChanged, true);
            app.cmbDevices.Tooltip = {'Device that generated epochs'};
            app.cmbDevices.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Clarinet

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClarinetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClarinetUIFigure)
        end
    end
end