classdef DialogFind < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        tblConditions  matlab.ui.control.Table
        btnOK          matlab.ui.control.Button
        btnAdd         matlab.ui.control.Button
        btnRemove      matlab.ui.control.Button
        btnClear       matlab.ui.control.Button
    end

    
    properties (Access = private)
        params      % Description
        selectedRow % Row selected on the table
        Parent      % Application calling this dialog
    end
    
    properties (Access = public)
        Conditions % Description
    end
    
    methods (Access = public)
        
        function setParameters(app, params)
            app.params = params;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            app.Parent = parent;
        end

        % Button pushed function: btnClear
        function btnClearButtonPushed(app, event)
            app.tblConditions.Data = [];
        end

        % Button pushed function: btnAdd
        function btnAddButtonPushed(app, event)
            CONDITIONS = categorical({'=='; '>'; '<'; '>='; '<='; '~='; 'strcmpi'; 'strfind'; 'strcmp'; 'regexp'});
            row = table({'epochNum'}, CONDITIONS(3), {'4'});
            if isempty(app.tblConditions.Data)
                app.tblConditions.Data = row;
            else
                app.tblConditions.Data = [app.tblConditions.Data; row];
            end    
        end

        % Cell selection callback: tblConditions
        function tblConditionsCellSelection(app, event)
            indices = event.Indices;
            app.selectedRow = indices;
        end

        % Button pushed function: btnRemove
        function btnRemoveButtonPushed(app, event)
            if app.selectedRow
                app.tblConditions.Data
            end
        end

        % Cell edit callback: tblConditions
        function tblConditionsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: btnOK
        function btnOKButtonPushed(app, event)
            assignin('base','data',app.tblConditions.Data);
            app.Parent.findEpochs(app.tblConditions.Data);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Define criteria to be met';
            app.tblConditions = uitable(app.UIFigure);
            app.tblConditions.ColumnName = {'Property'; 'Condition'; 'Value'};
            app.tblConditions.RowName = {};
            app.tblConditions.ColumnEditable = true;
            app.tblConditions.CellEditCallback = createCallbackFcn(app, @tblConditionsCellEdit, true);
            app.tblConditions.CellSelectionCallback = createCallbackFcn(app, @tblConditionsCellSelection, true);
            app.btnOK = uibutton(app.UIFigure, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @btnOKButtonPushed, true);
            app.btnOK.Text = 'OK';
            app.btnAdd = uibutton(app.UIFigure, 'push');
            app.btnAdd.ButtonPushedFcn = createCallbackFcn(app, @btnAddButtonPushed, true);
            app.btnAdd.Icon = '48px-List-add.png';
            app.btnAdd.Text = '';
            app.btnRemove = uibutton(app.UIFigure, 'push');
            app.btnRemove.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveButtonPushed, true);
            app.btnRemove.Icon = '48px-List-remove.png';
            app.btnRemove.Text = '';
            app.btnClear = uibutton(app.UIFigure, 'push');
            app.btnClear.ButtonPushedFcn = createCallbackFcn(app, @btnClearButtonPushed, true);
            app.btnClear.Icon = '48px-list-clear.png';
            app.btnClear.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DialogFind(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end