classdef DialogGroupBy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        tblConditions     matlab.ui.control.Table
        btnOK             matlab.ui.control.Button
        btnAdd            matlab.ui.control.Button
        btnRemove         matlab.ui.control.Button
        btnClear          matlab.ui.control.Button
        cmbTags           matlab.ui.control.DropDown
        SplittagsinLabel  matlab.ui.control.Label
        cmbSplitMethod    matlab.ui.control.Switch
        TagLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        params              % Description
        selectedRows = [];  % Row selected on the table
        Parent              % Application calling this dialog
    end
    
    properties (Access = public)
        Conditions % Description
    end
    
    methods (Access = public)
        
        function setParameters(app, params)
            app.params = params;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            app.Parent = parent;
        end

        % Button pushed function: btnClear
        function btnClearButtonPushed(app, event)
            app.tblConditions.Data = [];
        end

        % Button pushed function: btnAdd
        function btnAddButtonPushed(app, event)
            row = table({app.cmbTags.Value});
            if isempty(app.tblConditions.Data)
                app.tblConditions.Data = row;
            else
                app.tblConditions.Data = [app.tblConditions.Data; row];
            end    
        end

        % Cell selection callback: tblConditions
        function tblConditionsCellSelection(app, event)
            indices = event.Indices;
            app.selectedRows = indices;
        end

        % Button pushed function: btnRemove
        function btnRemoveButtonPushed(app, event)
            if ~isempty(app.selectedRows)
                if size(app.tblConditions.Data,1)>1
                    app.tblConditions.Data(app.selectedRows(:,1),:) = []; % Remove selected row from table
                else
                    app.tblConditions.Data = []; % If only one rrow left, delete the entire table
                end
            end
        end

        % Cell edit callback: tblConditions
        function tblConditionsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;            
        end

        % Button pushed function: btnOK
        function btnOKButtonPushed(app, event)
            app.Parent.splitEpochsByTags(app.tblConditions.Data, app.cmbSplitMethod.Value);
            app.UIFigure.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Split by';
            app.tblConditions = uitable(app.UIFigure);
            app.tblConditions.ColumnName = {'Value'};
            app.tblConditions.RowName = {};
            app.tblConditions.ColumnEditable = true;
            app.tblConditions.CellEditCallback = createCallbackFcn(app, @tblConditionsCellEdit, true);
            app.tblConditions.CellSelectionCallback = createCallbackFcn(app, @tblConditionsCellSelection, true);
            app.btnOK = uibutton(app.UIFigure, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @btnOKButtonPushed, true);
            app.btnOK.Tooltip = {'Search epochs meeting specified critera'};
            app.btnOK.Text = 'OK';
            app.btnAdd = uibutton(app.UIFigure, 'push');
            app.btnAdd.ButtonPushedFcn = createCallbackFcn(app, @btnAddButtonPushed, true);
            app.btnAdd.Icon = '48px-List-add.png';
            app.btnAdd.Tooltip = {'Add a new condition'};
            app.btnAdd.Text = '';
            app.btnRemove = uibutton(app.UIFigure, 'push');
            app.btnRemove.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveButtonPushed, true);
            app.btnRemove.Icon = '48px-List-remove.png';
            app.btnRemove.Tooltip = {'Remove selected condition'};
            app.btnRemove.Text = '';
            app.btnClear = uibutton(app.UIFigure, 'push');
            app.btnClear.ButtonPushedFcn = createCallbackFcn(app, @btnClearButtonPushed, true);
            app.btnClear.Icon = '48px-list-clear.png';
            app.btnClear.Tooltip = {'Clear all conditions'};
            app.btnClear.Text = '';
            app.cmbTags = uidropdown(app.UIFigure);
            app.cmbTags.Items = {};
            app.cmbTags.Value = {};
            app.SplittagsinLabel = uilabel(app.UIFigure);
            app.SplittagsinLabel.Text = 'Split tags in:';
            app.cmbSplitMethod = uiswitch(app.UIFigure, 'slider');
            app.cmbSplitMethod.Items = {'Parallel', 'Sequence'};
            app.cmbSplitMethod.Value = 'Parallel';
            app.TagLabel = uilabel(app.UIFigure);
            app.TagLabel.Text = 'Tag:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DialogGroupBy(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end